classdef Group06 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditField2                   matlab.ui.control.NumericEditField
        EditField2Label              matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        SaveImageButton              matlab.ui.control.Button
        ProcessImageButton           matlab.ui.control.Button
        ChooseAnOptionDropDown       matlab.ui.control.DropDown
        ChooseAnOptionDropDownLabel  matlab.ui.control.Label
        UploadImageButton            matlab.ui.control.Button
        ImageProcessingProgramLabel  matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imagepath;
        img % Description
        img2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)

          [file, path]= uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                else
                app.SaveImageButton.Enable = "off";
                app.ProcessImageButton.Enable ="off";

                cla(app.UIAxes2) 
                fullpath = strcat(path,file);
                app.imagepath = fullpath;
                app.img = imread(fullpath);
                imshow(app.img,'Parent',app.UIAxes);
                app.ChooseAnOptionDropDown.Enable ="on";
                
            end
           
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
             [filename,pathname] = uiputfile({'*.jpg';'*.png'},...
            'Save Filtered Image');

        if isequal(filename,0) || isequal(pathname,0)
           return; 
        end

        fullFilename = fullfile(pathname,filename);
        imwrite(app.img2, fullFilename);
       
        end

        % Button pushed function: ProcessImageButton
        function ProcessImageButtonPushed(app, event)
d = uiprogressdlg(app.UIFigure, 'Title','Integrating Image',... 
    'Indeterminate', 'on');
drawnow

app.SaveImageButton.Enable = "on";
value = app.ChooseAnOptionDropDown.Value;

switch value
    case 'Histogram Equalization'
        app.img2 = uint8(histogramEqualization(app.img));
        imshow(app.img2,'Parent',app.UIAxes2);

    case 'Mean Filtering'
        filter = app.EditField.Value;
        app.img2 = uint8(meanFilter(app.imagepath, filter));
        imshow(app.img2,'Parent',app.UIAxes2);
        
    case 'Median Filtering'
        filter = app.EditField.Value;
        app.img2 = uint8(medianFilter(app.img, filter));
        imshow(app.img2,'Parent',app.UIAxes2);
        
    case 'Adaptive Median Filtering'
        filter = app.EditField.Value;
        app.img2 = uint8(adaptiveMedianFilter(app.img, filter));
        imshow(app.img2,'Parent',app.UIAxes2);
        
    case 'Gaussian Filtering'
        filter = app.EditField.Value;
        app.img2 = uint8(gaussianFilter(app.img, filter));
        imshow(app.img2,'Parent',app.UIAxes2);
        
    case 'Additive Uniform Noise'
        a = app.EditField.Value;
        b = app.EditField2.Value;
        app.img2 = uniformNoise(app.imagepath, a, b);
        imshow(app.img2,[min(app.img2(:)),max(app.img2(:))],'Parent',app.UIAxes2);
        
    case 'Additive Gaussian Noise'
        a = app.EditField.Value;
        b = app.EditField2.Value;
        app.img2 = gaussianNoise(app.imagepath, a, b);
        imshow(app.img2,[min(app.img2(:)),max(app.img2(:))],'Parent',app.UIAxes2);
        
    case 'Additive Salt&Pepper Noise'
        a = app.EditField.Value;
        b = app.EditField2.Value;
        app.img2 = saltpepperNoise(app.imagepath, a, b);
        imshow(app.img2,[min(app.img2(:)),max(app.img2(:))],'Parent',app.UIAxes2);
        
    case 'Additive LogNormal Noise'
        a = app.EditField.Value;
        b = app.EditField2.Value;
        app.img2 = lognormalNoise(app.imagepath, a, b);
        imshow(app.img2,[min(app.img2(:)),max(app.img2(:))],'Parent',app.UIAxes2);
        
    case 'Additive Rayleigh Noise'
        a = app.EditField.Value;
        b = app.EditField2.Value;
        app.img2 = rayleighNoise(app.img, a, b);
        imshow(app.img2,[min(app.img2(:)),max(app.img2(:))],'Parent',app.UIAxes2);
        
    case 'Additive Exponential Noise'
        a = app.EditField.Value;
        b = app.EditField2.Value;
        app.img2 = exponentialNoise(app.imagepath, a, b);
        imshow(app.img2,[min(app.img2(:)),max(app.img2(:))],'Parent',app.UIAxes2);
        
    case 'Additive Erlang Noise'
        a = app.EditField.Value;
        b = app.EditField2.Value;
        app.img2 = erlangNoise(app.imagepath, a, b);
        imshow(app.img2,[min(app.img2(:)),max(app.img2(:))],'Parent',app.UIAxes2);
end

% close the dialog box
close(d);


        end

        % Value changed function: ChooseAnOptionDropDown
        function ChooseAnOptionDropDownValueChanged(app, event)
              app.ProcessImageButton.Enable="on";
            
            value = app.ChooseAnOptionDropDown.Value;
          
            
            switch value
                case 'None'
                    app.EditFieldLabel.Visible = 'off';
                    app.EditField2Label.Visible = 'off';
                    app.EditField.Visible = 'off';
                    app.EditField2.Visible = 'off';
                case 'Histogram Equalization'
                    
                    app.EditFieldLabel.Visible = 'off';
                    
                    app.EditField2Label.Visible = 'off';
                    app.EditField.Visible = 'off';
                    app.EditField2.Visible = 'off';
                case 'Mean Filtering'
                    app.EditField.Visible = 'on';
                    app.EditFieldLabel.Text = 'Filter Size:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'off';
                    app.EditField2Label.Visible = 'off';
                case 'Median Filtering'
                    app.EditField.Visible = 'on';
                    app.EditFieldLabel.Text = 'Filter Size:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'off';
                    app.EditField2Label.Visible = 'off';
                case 'Adaptive Median Filtering'
                    app.EditField.Visible = 'on';
                    app.EditFieldLabel.Text = 'Maximum Window Size:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'off';
                    app.EditField2Label.Visible = 'off';
                case 'Gaussian Filtering'
                    app.EditField.Visible = 'on';
                    app.EditFieldLabel.Text = 'Standard Deviation Value:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'off';
                    app.EditField2Label.Visible = 'off';
                case 'Additive Uniform Noise'
                    app.EditField.Visible = 'on';
                    app.EditField.Value = 0;
                    app.EditField2.Value = 1;
                    app.EditFieldLabel.Text = 'Parameter A:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'on';
                    app.EditField2Label.Text = 'Parameter B:';
                    app.EditField2Label.Visible = 'on';
                case 'Additive Gaussian Noise'
                    app.EditField.Visible = 'on';
                    app.EditField.Value = 0;
                    app.EditField2.Value = 1;
                    app.EditFieldLabel.Text = 'Parameter A:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'on';
                    app.EditField2Label.Text = 'Parameter B:';
                    app.EditField2Label.Visible = 'on';
                case 'Additive Salt&Pepper Noise'
                    app.EditField.Visible = 'on';
                    app.EditField.Value = 0.5;
                    app.EditField2.Value = 0.5;
                    app.EditFieldLabel.Text = 'Parameter A:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'on';
                    app.EditField2Label.Text = 'Parameter B:';
                    app.EditField2Label.Visible = 'on';
                case 'Additive LogNormal Noise'
                    app.EditField.Visible = 'on';
                    app.EditField.Value = 1;
                    app.EditField2.Value = 0.25;
                    app.EditFieldLabel.Text = 'Parameter A:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'on';
                    app.EditField2Label.Text = 'Parameter B:';
                    app.EditField2Label.Visible = 'on';
                case 'Additive Rayleigh Noise'
                    app.EditField.Visible = 'on';
                    app.EditField.Value = 0;
                    app.EditField2.Value = 1;
                    app.EditFieldLabel.Text = 'Parameter A:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'on';
                    app.EditField2Label.Text = 'Parameter B:';
                    app.EditField2Label.Visible = 'on';
                case 'Additive Exponential Noise'
                    app.EditField.Visible = 'on';
                    app.EditField.Value = 1;
                    app.EditFieldLabel.Text = 'Parameter A:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'on';
                    app.EditField2Label.Text = 'Parameter B:';
                    app.EditField2Label.Visible = 'on';
                case 'Additive Erlang Noise'
                    app.EditField.Visible = 'on';
                    app.EditField.Value = 2;
                    app.EditField2.Value = 5;
                    app.EditFieldLabel.Text = 'Parameter A:';
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField2.Visible = 'on';
                    app.EditField2Label.Text = 'Parameter B:';
                    app.EditField2Label.Visible = 'on';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
% Display a confirmation dialog
    selection = uiconfirm(app.UIFigure, ...
        'Are you sure you want to reset The selected images?', ...
        'Confirmation', ...
        'Options', {'Yes', 'Cancel'}, ...
        'DefaultOption', 'Cancel');
    
    % Check the user's selection
    if strcmp(selection, 'Yes')
        % Reset image data to empty matrices
        cla(app.UIAxes);
        cla(app.UIAxes2);
        title(app.UIAxes, 'Original Image');
        title(app.UIAxes2, 'Changed Image');
        app.img = [];
        app.img2 = [];
    else
        % Do nothing or handle the cancel action
    end

    cla(app.UIAxes);
    cla(app.UIAxes2);
    title(app.UIAxes, 'Original Image');
    title(app.UIAxes2, 'Changed Image');
    
    % Reset image data to empty matrices
    app.img = [];
    app.img2 = [];

        end

        % Drop down opening function: ChooseAnOptionDropDown
        function ChooseAnOptionDropDownOpening(app, event)
            
        end

        % Clicked callback: ChooseAnOptionDropDown
        function ChooseAnOptionDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Changed Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.ImageProcessingProgramLabel = uilabel(app.UIFigure);
            app.ImageProcessingProgramLabel.Text = 'Image Processing Program';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.ChooseAnOptionDropDownLabel = uilabel(app.UIFigure);
            app.ChooseAnOptionDropDownLabel.Text = 'Choose An Option';
            app.ChooseAnOptionDropDown = uidropdown(app.UIFigure);
            app.ChooseAnOptionDropDown.Items = {'None', 'Histogram Equalization', 'Mean Filtering', 'Median Filtering', 'Adaptive Median Filtering', 'Gaussian Filtering', 'Additive Uniform Noise', 'Additive Gaussian Noise', 'Additive Salt&Pepper Noise', 'Additive Rayleigh Noise', 'Additive Exponential Noise', 'Additive Erlang Noise'};
            app.ChooseAnOptionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseAnOptionDropDownOpening, true);
            app.ChooseAnOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseAnOptionDropDownValueChanged, true);
            app.ChooseAnOptionDropDown.ClickedFcn = createCallbackFcn(app, @ChooseAnOptionDropDownClicked, true);
            app.ChooseAnOptionDropDown.Value = 'None';
            app.ProcessImageButton = uibutton(app.UIFigure, 'push');
            app.ProcessImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImageButtonPushed, true);
            app.ProcessImageButton.Text = 'Process Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Visible = 'off';
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Visible = 'off';
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Visible = 'off';
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group06

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end